classdef combinebestbinsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        CurrentBinsLabel                matlab.ui.control.Label
        CurrentlyLoadedBESTsetEditFieldLabel  matlab.ui.control.Label
        CurrentlyLoadedBESTsetEditField  matlab.ui.control.EditField
        CreatecombinedbinsPanel         matlab.ui.container.Panel
        EditField                       matlab.ui.control.EditField
        EnterarrayofbinstocombineLabel  matlab.ui.control.Label
        Forexample12or15Label           matlab.ui.control.Label
        EnternewbinlabelLabel           matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        ApplyButton                     matlab.ui.control.Button
        UITable_2                       matlab.ui.control.Table
        BinsofnewBESTsetLabel           matlab.ui.control.Label
        FinishButton                    matlab.ui.control.Button
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %inputs
            ALLBEST = varargin{1};
            currset = varargin{2};
            bins_to_combine = varargin{3}{1}; 
            bin_labels = varargin{3}{2};
            
            
            %% Obtain bins
            %% only BESTset at a time
            BEST = ALLBEST(currset); 
            n_bins = numel(BEST.binwise_data);
            
            % Load BESTname
            app.CurrentlyLoadedBESTsetEditField.Value = BEST.bestname; 
            
            %create original datatable
            og_nbins = cellstr(string(1:n_bins)) ; 
            og_data = {BEST.bindesc{:}}; 
            og_ntrials = cellstr(string(BEST.n_trials_per_bin)); 
            app.UITable.Data = [og_nbins',og_data',og_ntrials'];


            valid_eeg = zeros(n_eeg,1); %index
            valid_eeg_logical = zeros(n_eeg,1); %for GUI             
         
            for i = 1:n_eeg
                    
                if ~isempty(ALLEEG(i).epoch)
                    
                    valid_eeg(i) = i;
                    valid_eeg_logical(i) = 1; 
                    
                else
                    valid_eeg_logical(i) = 0; 
                    
                end
                
            end
            
            
            %add to table component (list ACTIVE ONLY)
            EEGnames = {ALLEEG(currset).setname};
            
            %cnames = table(EEGnames, valid_eeg_logical,bin_check);
            app.UITable2.Data = EEGnames; % OneSet


            %save into app
            app.BEST = BEST; 
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.CurrentBinsLabel = uilabel(app.UIFigure);
            app.CurrentBinsLabel.Text = 'Current Bins ';
            app.CurrentlyLoadedBESTsetEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentlyLoadedBESTsetEditFieldLabel.Text = 'Currently Loaded BESTset';
            app.CurrentlyLoadedBESTsetEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentlyLoadedBESTsetEditField.Tag = 'bestset_name';
            app.CreatecombinedbinsPanel = uipanel(app.UIFigure);
            app.CreatecombinedbinsPanel.Title = 'Create combined bins';
            app.EditField = uieditfield(app.CreatecombinedbinsPanel, 'text');
            app.EditField.Tag = 'bin_combo';
            app.EnterarrayofbinstocombineLabel = uilabel(app.CreatecombinedbinsPanel);
            app.EnterarrayofbinstocombineLabel.Text = 'Enter array of bins to combine. ';
            app.Forexample12or15Label = uilabel(app.CreatecombinedbinsPanel);
            app.Forexample12or15Label.Text = 'For example: [1,2] or [1:5]';
            app.EnternewbinlabelLabel = uilabel(app.CreatecombinedbinsPanel);
            app.EnternewbinlabelLabel.Text = 'Enter new bin label: ';
            app.EditField_2 = uieditfield(app.CreatecombinedbinsPanel, 'text');
            app.ApplyButton = uibutton(app.CreatecombinedbinsPanel, 'push');
            app.ApplyButton.Text = 'Apply';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.BinsofnewBESTsetLabel = uilabel(app.UIFigure);
            app.BinsofnewBESTsetLabel.Text = 'Bins of new BESTset';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.Text = 'Finish';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = combinebestbinsGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end